classdef IQ_Bias_Control_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaserServoControlUIFigure       matlab.ui.Figure
        ManualControlPanel              matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        PID1Tab                         matlab.ui.container.Tab
        DivisorSpinner                  matlab.ui.control.Spinner
        DivisorSpinnerLabel             matlab.ui.control.Label
        KdSpinner                       matlab.ui.control.Spinner
        KdSpinnerLabel                  matlab.ui.control.Label
        KiSpinner                       matlab.ui.control.Spinner
        KiSpinnerLabel                  matlab.ui.control.Label
        KpSpinner                       matlab.ui.control.Spinner
        KpSpinnerLabel                  matlab.ui.control.Label
        LowerLimitEditField             matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel        matlab.ui.control.Label
        SetPointEditField               matlab.ui.control.NumericEditField
        SetPointEditField_2Label        matlab.ui.control.Label
        UpperLimitEditField             matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel        matlab.ui.control.Label
        PolaritySwitch                  matlab.ui.control.Switch
        PolaritySwitchLabel             matlab.ui.control.Label
        EnableSwitch                    matlab.ui.control.Switch
        EnableSwitchLabel               matlab.ui.control.Label
        ReadKdLabel                     matlab.ui.control.Label
        RealKiLabel                     matlab.ui.control.Label
        RealKdEditField                 matlab.ui.control.NumericEditField
        RealKiEditField                 matlab.ui.control.NumericEditField
        RealKpEditField                 matlab.ui.control.NumericEditField
        PID2Tab                         matlab.ui.container.Tab
        DivisorSpinner_2                matlab.ui.control.Spinner
        DivisorSpinner_2Label           matlab.ui.control.Label
        KdSpinner_2                     matlab.ui.control.Spinner
        KdSpinner_2Label                matlab.ui.control.Label
        KiSpinner_2                     matlab.ui.control.Spinner
        KiSpinner_2Label                matlab.ui.control.Label
        KpSpinner_2                     matlab.ui.control.Spinner
        KpSpinner_2Label                matlab.ui.control.Label
        LowerLimitEditField_2           matlab.ui.control.NumericEditField
        LowerLimitEditField_2Label      matlab.ui.control.Label
        SetPointEditField_2             matlab.ui.control.NumericEditField
        SetPointEditField_3Label        matlab.ui.control.Label
        UpperLimitEditField_2           matlab.ui.control.NumericEditField
        UpperLimitEditField_2Label      matlab.ui.control.Label
        PolaritySwitch_2                matlab.ui.control.Switch
        PolaritySwitch_2Label           matlab.ui.control.Label
        EnableSwitch_2                  matlab.ui.control.Switch
        EnableSwitch_2Label             matlab.ui.control.Label
        RealKdLabel_2                   matlab.ui.control.Label
        RealKiLabel_2                   matlab.ui.control.Label
        RealKdEditField_2               matlab.ui.control.NumericEditField
        RealKiEditField_2               matlab.ui.control.NumericEditField
        RealKpEditField_2               matlab.ui.control.NumericEditField
        PID3Tab                         matlab.ui.container.Tab
        DivisorSpinner_3                matlab.ui.control.Spinner
        DivisorSpinner_3Label           matlab.ui.control.Label
        KdSpinner_3                     matlab.ui.control.Spinner
        KdSpinner_3Label                matlab.ui.control.Label
        KiSpinner_3                     matlab.ui.control.Spinner
        KiSpinner_3Label                matlab.ui.control.Label
        KpSpinner_3                     matlab.ui.control.Spinner
        KpSpinner_3Label                matlab.ui.control.Label
        LowerLimitEditField_3           matlab.ui.control.NumericEditField
        LowerLimitEditField_3Label      matlab.ui.control.Label
        SetPointEditField_3             matlab.ui.control.NumericEditField
        SetPointEditField_3Label_2      matlab.ui.control.Label
        UpperLimitEditField_3           matlab.ui.control.NumericEditField
        UpperLimitEditField_3Label      matlab.ui.control.Label
        PolaritySwitch_3                matlab.ui.control.Switch
        PolaritySwitch_3Label           matlab.ui.control.Label
        EnableSwitch_3                  matlab.ui.control.Switch
        EnableSwitch_3Label             matlab.ui.control.Label
        RealKdLabel_3                   matlab.ui.control.Label
        RealKiLabel_3                   matlab.ui.control.Label
        RealKdEditField_3               matlab.ui.control.NumericEditField
        RealKiEditField_3               matlab.ui.control.NumericEditField
        RealKpEditField_3               matlab.ui.control.NumericEditField
        ApplicationSettingsPanel        matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        YLim2EditField                  matlab.ui.control.EditField
        YLim2EditFieldLabel             matlab.ui.control.Label
        YLim1EditField                  matlab.ui.control.EditField
        YLim1Label                      matlab.ui.control.Label
        PlotDisplayDropDown             matlab.ui.control.DropDown
        YAxisDropDown                   matlab.ui.control.DropDown
        YAxisDropDownLabel              matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        XAxisDropDownLabel              matlab.ui.control.Label
        PlotDropDown                    matlab.ui.control.DropDown
        PlotUpdateTimesEditField        matlab.ui.control.NumericEditField
        PlotUpdateTimesEditFieldLabel   matlab.ui.control.Label
        FetchDataButton                 matlab.ui.control.Button
        RetrievescandataSwitch          matlab.ui.control.Switch
        RetrievescandataSwitchLabel     matlab.ui.control.Label
        FetchButton                     matlab.ui.control.Button
        UploadButton                    matlab.ui.control.Button
        AutoUpdateSwitch                matlab.ui.control.Switch
        AutoUpdateSwitchLabel           matlab.ui.control.Label
        AcquisitionSettingsPanel        matlab.ui.container.Panel
        Log2ofCICshiftSpinner           matlab.ui.control.Spinner
        Log2ofCICshiftSpinnerLabel      matlab.ui.control.Label
        DemodPhase2degEditField         matlab.ui.control.NumericEditField
        DemodPhase2degEditFieldLabel    matlab.ui.control.Label
        DemodPhase1degEditField         matlab.ui.control.NumericEditField
        DemodPhase1degEditFieldLabel    matlab.ui.control.Label
        DriveamplitudeVEditField        matlab.ui.control.NumericEditField
        DriveamplitudeVEditFieldLabel   matlab.ui.control.Label
        ModulationFreqHzEditField       matlab.ui.control.NumericEditField
        ModulationFreqHzEditFieldLabel  matlab.ui.control.Label
        BWHzEditField                   matlab.ui.control.NumericEditField
        BWHzEditFieldLabel              matlab.ui.control.Label
        dtsEditField                    matlab.ui.control.NumericEditField
        dtsEditFieldLabel               matlab.ui.control.Label
        Log2CICRateSpinner              matlab.ui.control.Spinner
        Log2CICRateSpinnerLabel         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        servo                           LaserServo % LaserServo control
        autoUpdate % Description
        int_timer % Description
        plotType % Description
    end
    
    methods (Access = private)
        
        function results = updateServo(app)
            if strcmpi(app.AutoUpdateSwitch.Value,'On') && strcmpi(app.RetrievescandataSwitch.Value,'Off')
                app.servo.autoset.upload;
            end
            app.updateDisplay;
        end
        
        function results = updateDisplay(app)
            %
            % Shorten names
            %
            s = app.servo;
            scan = s.scan;
            pid = s.pid;
            %
            % Top level settings
            %
            app.ErrorSignalInputDropDown.Value = s.inputSelect.value;
            %
            % Filtering settings
            %
            app.Log2CICRateSpinner.Value = s.log2Avgs.value;
            app.dtsEditField.Value = s.dt();
            app.BWHzEditField.Value = 1/(2*s.dt());
            %
            % Data collection
            %
            app.FIFO1DropDown.Value = s.fifoRoute(1).value;
            app.FIFO2DropDown.Value = s.fifoRoute(2).value;
            %
            % Scan control
            %
            app.OffsetmVKnob.Value = scan.offset.value*1e3;
            app.AmplitudemVKnob.Value = scan.amplitude.value*1e3;
            app.OffsetField.Value = scan.offset.value*1e3;
            app.AmplitudeField.Value = s.scan.amplitude.value*1e3;
            if scan.enable.value
                app.EnableScanSwitch.Value = 'On';
            else
                app.EnableScanSwitch.Value = 'Off';
            end
            app.ScanDurationmsEditField.Value = scan.duration*1e3;
            if pid(1).scanEnable.value == 1 && pid(2).scanEnable.value == 0
                app.RoutescantoDropDown.Value = 'PID1';
            elseif pid(1).scanEnable.value == 0 && pid(2).scanEnable.value == 1
                app.RoutescantoDropDown.Value = 'PID2';
            elseif pid(1).scanEnable.value == 0 && pid(2).scanEnable.value == 0
                app.RoutescantoDropDown.Value = 'None';
            elseif pid(1).scanEnable.value == 1 && pid(2).scanEnable.value == 1
                app.RoutescantoDropDown.Value = 'Both';
            end
            %
            % PID1 settings
            %
            if pid(1).enable.value
                app.EnableSwitch.Value = 'On';
            else
                app.EnableSwitch.Value = 'Off';
            end
            if pid(1).polarity.value == 0
                app.PolaritySwitch.Value = 'Negative';
            else
                app.PolaritySwitch.Value = 'Positive';
            end
            app.KpSpinner.Value = pid(1).Kp.value;
            app.KiSpinner.Value = pid(1).Ki.value;
            app.KdSpinner.Value = pid(1).Kd.value;
            app.DivisorSpinner.Value = pid(1).divisor.value;
            app.UpperLimitEditField.Value = pid(1).upperLimit.value;
            app.LowerLimitEditField.Value = pid(1).lowerLimit.value;
            app.SetPointEditField.Value = pid(1).control.value;
            [Kp,Ki,Kd] = pid(1).calculateRealGains;
            app.RealKpEditField.Value = Kp;
            app.RealKiEditField.Value = Ki;
            app.RealKdEditField.Value = Kd;
            %
            % PID2 settings
            %
            if pid(2).enable.value
                app.EnableSwitch_2.Value = 'On';
            else
                app.EnableSwitch_2.Value = 'Off';
            end
            if pid(2).polarity.value == 0
                app.PolaritySwitch_2.Value = 'Negative';
            else
                app.PolaritySwitch_2.Value = 'Positive';
            end
            app.KpSpinner_2.Value = pid(2).Kp.value;
            app.KiSpinner_2.Value = pid(2).Ki.value;
            app.KdSpinner_2.Value = pid(2).Kd.value;
            app.DivisorSpinner_2.Value = pid(2).divisor.value;
            app.UpperLimitEditField_2.Value = pid(2).upperLimit.value;
            app.LowerLimitEditField_2.Value = pid(2).lowerLimit.value;
            app.SetPointEditField_2.Value = pid(2).control.value;
            [Kp,Ki,Kd] = pid(2).calculateRealGains;
            app.RealKpEditField_2.Value = Kp;
            app.RealKiEditField_2.Value = Ki;
            app.RealKdEditField_2.Value = Kd;
            %
            % Lock-in detection
            %
            app.ModulationFreqHzEditField.Value = s.lockin.driveFreq.value;
            app.DemodulationFreqHzEditField.Value = s.lockin.demodFreq.value;
            app.DemodPhase1degEditField.Value = s.lockin.demodPhase.value;
            app.Log2ofCICrateSpinner.Value = s.lockin.cicRate.value;
            app.LockinADCDropDown.Value = s.lockinSelect.value;
            app.DriveamplitudeVEditField.Value = s.lockin.driveAmp.value;
            app.OUT1DropDown.Value = s.outputSelect(1).value;
            app.OUT2DropDown.Value = s.outputSelect(2).value;
            app.Log2ofCICshiftSpinner.Value = s.lockin.shift.value;
        end
        
        function results = updatePlot(app)
            app.servo.autoset.upload;
            app.servo.getScanData;

            if strcmpi(app.PlotDisplayDropDown.Value,'fifo 1')
                d(1).v = app.servo.data(:,1);
                d(1).t = app.servo.t;
                d(2).v = nan(size(app.servo.t));
                d(2).t = nan(size(app.servo.t));
            elseif strcmpi(app.PlotDisplayDropDown.Value,'fifo 2')
                d(1).v = nan(size(app.servo.t));
                d(1).t = nan(size(app.servo.t));
                d(2).v = app.servo.data(:,2);
                d(2).t = app.servo.t;
            else
                d(1).v = app.servo.data(:,1);
                d(1).t = app.servo.t;
                d(2).v = app.servo.data(:,2);
                d(2).t = app.servo.t;
            end
            
            clr = {'#0072BD','#D95319'};
            if strcmpi(app.PlotDropDown.Value,'time')
                yyaxis(app.UIAxes,'left');
                plot(app.UIAxes,d(1).t,d(1).v,'.-','color',clr{1});
                app.UIAxes.YLabel.String = 'Signal [V]';
                
                set(app.UIAxes,'YScale','lin');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,d(2).t,d(2).v,'.-','color',clr{2});
                
                set(app.UIAxes,'Xscale','lin','YScale','lin');
                app.UIAxes.XLabel.String = 'Time [s]';
                app.UIAxes.YLabel.String = 'Signal [V]';
                set(app.UIAxes,'Xlim',[app.servo.t(1),app.servo.t(end)]);
            elseif strcmpi(app.PlotDropDown.Value,'volts')
                [t,v] = app.servo.scan.estimateScan();
                vinterp = interp1(t,v,app.servo.t,'linear');
                
                yyaxis(app.UIAxes,'left');
                plot(app.UIAxes,vinterp,d(1).v,'.-','color',clr{1});
                app.UIAxes.YLabel.String = 'Signal [V]';
                
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,vinterp,d(2).v,'.-','color',clr{2});
                app.UIAxes.YLabel.String = 'Signal [V]';
                
                set(app.UIAxes,'Xscale','lin','YScale','lin');
                app.UIAxes.XLabel.String = 'Scan [V]';
                app.UIAxes.YLabel.String = 'Signal [V]';
                set(app.UIAxes,'XLim',[min(vinterp),max(vinterp)]);
            elseif strcmpi(app.PlotDropDown.Value,'psd')
                dt = app.servo.t(2) - app.servo.t(1);
                N = size(app.servo.data,1);
                f = 1/dt*(0:(N-1))/N;
                f = f(1:floor(N/2))';
                Y = zeros(N,2);
                for nn = 1:2
                    Y(:,nn) = fft(detrend(d(nn).v,1),N,1);
                end
                Y = Y(1:floor(N/2),:);
                Y(1,:) = Y(1,:)/N;
                Y(2:end,:) = 2*Y(2:end,:)/N;
                Y = Y/sqrt(2);
                P = abs(Y).^2/(f(2) - f(1));
                yyaxis(app.UIAxes,'left');
                plot(app.UIAxes,f,P(:,1),'.-');
                set(app.UIAxes,'Xscale',app.XAxisDropDown.Value,'YScale',app.YAxisDropDown.Value);
                set(app.UIAxes,'Xlim',[f(2),f(end)]);
                app.UIAxes.XLabel.String = 'Freq [Hz]';
                app.UIAxes.YLabel.String = 'PSD [V^2/Hz]';
                
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,f,P(:,2),'.-');
                set(app.UIAxes,'Xscale',app.XAxisDropDown.Value,'YScale',app.YAxisDropDown.Value);
                set(app.UIAxes,'Xlim',[f(2),f(end)]);
                app.UIAxes.XLabel.String = 'Freq [Hz]';
                app.UIAxes.YLabel.String = 'PSD [V^2/Hz]';
            end
            
            set(app.UIAxes,'XGrid','on','YGrid','on')
            lims1 = sscanf(app.YLim1EditField.Value,'%f,%f');
            lims2 = sscanf(app.YLim2EditField.Value,'%f,%f');
            if ~isempty(lims1)
                try
                    yyaxis(app.UIAxes,'left');
                    set(app.UIAxes,'YLim',sort(lims1));
                catch
                    
                end
            end
            if ~isempty(lims2)
                try
                    yyaxis(app.UIAxes,'right');
                    set(app.UIAxes,'YLim',sort(lims2));
                catch
                    
                end
            end
            yyaxis(app.UIAxes,'left');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin < 2
                app.servo = LaserServo;
%                 app.servo.setDefaults;
            elseif isa(varargin{1},'LaserServo')
                app.servo = varargin{1};
            elseif ischar(varargin{1}) || isstring(varargin{1})
                if numel(varargin) == 1
                    app.servo = LaserServo(varargin{1});
                else
                    app.servo = LaserServo(varargin{1},varargin{2});
                end
                app.servo.setDefaults;
            else
                error('Input arguments must be either a ''LaserServo'' object or an IP address and port number!');
            end
            
            if nargin == 2 && ~isa(varargin{1},'LaserServo')
                try
                    app.servo.conn.open;
                    app.servo.fetch;
                catch ME
                    warning('A connection to the socket server could not be established!');
                end
            end
            app.updateDisplay;
            warning('off','MATLAB:callback:error');
%             app.LaserServoControlUIFigure.CloseRequestFcn = @(~,~) warning('on','MATLAB:callback:error');
            
        end

        % Value changed function: Log2CICRateSpinner
        function Log2CICRateSpinnerValueChanged(app, event)
            value = app.Log2CICRateSpinner.Value;
            app.servo.log2Avgs.set(value);
            app.updateServo;
        end

        % Callback function
        function FIFO1DropDownValueChanged(app, event)
            value = app.FIFO1DropDown.Value;
            app.servo.fifoRoute(1).set(value);
            app.updateServo;
        end

        % Callback function
        function FIFO2DropDownValueChanged(app, event)
            value = app.FIFO2DropDown.Value;
            app.servo.fifoRoute(2).set(value);
            app.updateServo;
        end

        % Callback function
        function OffsetmVKnobValueChanged(app, event)
            value = app.OffsetmVKnob.Value;
            app.servo.scan.offset.set(value/1e3);
            app.updateServo;
        end

        % Callback function
        function AmplitudemVKnobValueChanged(app, event)
            value = app.AmplitudemVKnob.Value;
            app.servo.scan.amplitude.set(value/1e3);
            app.servo.scan.setScanSteps;
            app.updateServo;
        end

        % Callback function
        function EnableScanSwitchValueChanged(app, event)
            value = app.EnableScanSwitch.Value;
            if strcmpi(value,'off')
                value = 0;
            else
                value = 1;
            end
            app.servo.scan.enable.set(value);
            app.updateServo;
        end

        % Callback function
        function ScanDurationmsEditFieldValueChanged(app, event)
            value = app.ScanDurationmsEditField.Value;
            app.servo.scan.setScanSteps(value/1e3);
            app.updateServo;
        end

        % Callback function
        function RoutescantoDropDownValueChanged(app, event)
            value = app.RoutescantoDropDown.Value;
            if strcmpi(value,'pid1')
                app.servo.pid(1).scanEnable.set(1);
                app.servo.pid(2).scanEnable.set(0);
            elseif strcmpi(value,'pid2')
                app.servo.pid(1).scanEnable.set(0);
                app.servo.pid(2).scanEnable.set(1);
            elseif strcmpi(value,'none')
                app.servo.pid(1).scanEnable.set(0);
                app.servo.pid(2).scanEnable.set(0);
            elseif strcmpi(value,'both')
                app.servo.pid(1).scanEnable.set(1);
                app.servo.pid(2).scanEnable.set(1);
            end
            app.updateServo;
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
            value = app.EnableSwitch.Value;
            if strcmpi(value,'off')
                value = 0;
            else
                value = 1;
            end
            app.servo.pid(1).enable.set(value);
            app.updateServo;
        end

        % Value changed function: PolaritySwitch
        function PolaritySwitchValueChanged(app, event)
            value = app.PolaritySwitch.Value;
            if strcmpi(value,'negative')
                value = 0;
            elseif strcmpi(value,'positive')
                value = 1;
            end
            app.servo.pid(1).polarity.set(value);
            app.updateServo;
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            value = app.UpperLimitEditField.Value;
            app.servo.pid(1).upperLimit.set(value);
            app.updateServo;
        end

        % Value changed function: SetPointEditField
        function SetPointEditFieldValueChanged(app, event)
            value = app.SetPointEditField.Value;
            app.servo.pid(1).control.set(value);
            app.updateServo;
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            value = app.LowerLimitEditField.Value;
            app.servo.pid(1).lowerLimit.set(value);
            app.updateServo;
        end

        % Value changed function: EnableSwitch_2
        function EnableSwitch_2ValueChanged(app, event)
            value = app.EnableSwitch_2.Value;
            if strcmpi(value,'off')
                value = 0;
            else
                value = 1;
            end
            app.servo.pid(2).enable.set(value);
            app.updateServo;
        end

        % Value changed function: PolaritySwitch_2
        function PolaritySwitch_2ValueChanged(app, event)
            value = app.PolaritySwitch_2.Value;
            if strcmpi(value,'negative')
                value = 0;
            elseif strcmpi(value,'positive')
                value = 1;
            end
            app.servo.pid(2).polarity.set(value);
            app.updateServo;
        end

        % Value changed function: UpperLimitEditField_2
        function UpperLimitEditField_2ValueChanged(app, event)
            value = app.UpperLimitEditField_2.Value;
            app.servo.pid(2).upperLimit.set(value);
            app.updateServo;
        end

        % Value changed function: SetPointEditField_2
        function SetPointEditField_2ValueChanged(app, event)
            value = app.SetPointEditField_2.Value;
            app.servo.pid(2).control.set(value);
            app.updateServo;
        end

        % Value changed function: LowerLimitEditField_2
        function LowerLimitEditField_2ValueChanged(app, event)
            value = app.LowerLimitEditField_2.Value;
            app.servo.pid(2).lowerLimit.set(value);
            app.updateServo;
        end

        % Value changed function: AutoUpdateSwitch
        function AutoUpdateSwitchValueChanged(app, event)
            value = app.AutoUpdateSwitch.Value;
            if strcmpi(value,'off')
                app.autoUpdate = 0;
            else
                app.autoUpdate = 1;
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.servo.upload;
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            app.servo.fetch;
            app.updateDisplay;
        end

        % Button pushed function: FetchDataButton
        function FetchDataButtonPushed(app, event)
            app.updatePlot;
        end

        % Callback function
        function OffsetFieldValueChanged(app, event)
            value = app.OffsetField.Value;
            app.servo.scan.offset.set(value/1e3);
            app.updateServo;
        end

        % Callback function
        function AmplitudeFieldValueChanged(app, event)
            value = app.AmplitudeField.Value;
            app.servo.scan.amplitude.set(value/1e3);
            app.updateServo;
        end

        % Value changed function: RetrievescandataSwitch
        function RetrievescandataSwitchValueChanged(app, event)
            value = app.RetrievescandataSwitch.Value;
            if strcmpi(value,'off')
                if isa(app.int_timer,'timer')
                    stop(app.int_timer);
                    delete(app.int_timer);
                end
            elseif strcmpi(value,'on')
                app.int_timer = timer('ExecutionMode','fixedSpacing','Name','Scan Retrieval timer','Period',app.PlotUpdateTimesEditField.Value);
                app.int_timer.ErrorFcn = @(obj,event) delete(obj);
                app.int_timer.TimerFcn = @(~,~) app.updatePlot;
                start(app.int_timer);
            end
        end

        % Value changed function: KpSpinner
        function KpSpinnerValueChanged(app, event)
            value = app.KpSpinner.Value;
            app.servo.pid(1).Kp.set(value);
            app.updateServo;
        end

        % Value changed function: KiSpinner
        function KiSpinnerValueChanged(app, event)
            value = app.KiSpinner.Value;
            app.servo.pid(1).Ki.set(value);
            app.updateServo;
        end

        % Value changed function: KdSpinner
        function KdSpinnerValueChanged(app, event)
            value = app.KdSpinner.Value;
            app.servo.pid(1).Kd.set(value);
            app.updateServo;
        end

        % Value changed function: DivisorSpinner
        function DivisorSpinnerValueChanged(app, event)
            value = app.DivisorSpinner.Value;
            app.servo.pid(1).divisor.set(value);
            app.updateServo;
        end

        % Value changed function: KpSpinner_2
        function KpSpinner_2ValueChanged(app, event)
            value = app.KpSpinner_2.Value;
            app.servo.pid(2).Kp.set(value);
            app.updateServo;
        end

        % Value changed function: KiSpinner_2
        function KiSpinner_2ValueChanged(app, event)
            value = app.KiSpinner_2.Value;
            app.servo.pid(2).Ki.set(value);
            app.updateServo;
        end

        % Value changed function: KdSpinner_2
        function KdSpinner_2ValueChanged(app, event)
            value = app.KdSpinner_2.Value;
            app.servo.pid(2).Kd.set(value);
            app.updateServo;
        end

        % Value changed function: DivisorSpinner_2
        function DivisorSpinner_2ValueChanged(app, event)
            value = app.DivisorSpinner_2.Value;
            app.servo.pid(2).divisor.set(value);
            app.updateServo;
        end

        % Callback function
        function ErrorSignalInputDropDownValueChanged(app, event)
            value = app.ErrorSignalInputDropDown.Value;
            app.servo.inputSelect.set(value);
            app.updateServo;
        end

        % Window button up function: LaserServoControlUIFigure
        function LaserServoControlUIFigureWindowButtonUp(app, event)
            pos = app.UIAxes.CurrentPoint(1,1:2);
            if app.servo.pid(1).enable.value || app.servo.pid(2).enable.value
                return
            end
            if pos(1) < app.UIAxes.XLim(1) || pos(1) > app.UIAxes.XLim(2)
                return
            elseif pos(2) < app.UIAxes.YLim(1) || pos(2) > app.UIAxes.YLim(2)
                return
            end
            
            if strcmpi(app.PlotDropDown.Value,'volts')
                app.servo.scan.offset.set(pos(1));
                app.updateServo;
%             elseif strcmpi(app.PlotDropDown.Value,'time')
%                 [t,v] = app.servo.scan.estimateScan;
%                 vinterp = interp1(t,v,pos(1),'linear');
%                 app.servo.scan.offset.set(vinterp);
%                 app.updateServo;
            end
        end

        % Value changed function: PlotUpdateTimesEditField
        function PlotUpdateTimesEditFieldValueChanged(app, event)
            value = app.PlotUpdateTimesEditField.Value;
            if isa(app.int_timer,'timer') && isvalid(app.int_timer)
                app.int_timer.Period = value;
            end
        end

        % Value changed function: ModulationFreqHzEditField
        function ModulationFreqHzEditFieldValueChanged(app, event)
            value = app.ModulationFreqHzEditField.Value;
            app.servo.lockin.driveFreq.set(value);
            app.updateServo;
        end

        % Callback function
        function DemodulationFreqHzEditFieldValueChanged(app, event)
            value = app.DemodulationFreqHzEditField.Value;
            app.servo.lockin.demodFreq.set(value);
            app.updateServo;
        end

        % Value changed function: DemodPhase1degEditField
        function DemodPhase1degEditFieldValueChanged(app, event)
            value = app.DemodPhase1degEditField.Value;
            app.servo.lockin.demodPhase.set(value);
            app.updateServo;
        end

        % Callback function
        function Log2ofCICrateSpinnerValueChanged(app, event)
            value = app.Log2ofCICrateSpinner.Value;
            app.servo.lockin.cicRate.set(value);
            app.updateServo;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile;
            data = load([path,file],'saved_servo');
            app.servo.loadstruct(data.saved_servo);
            app.updateServo;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saved_servo = app.servo.struct;
            uisave('saved_servo','servo-parameters');
        end

        % Callback function
        function LockinADCDropDownValueChanged(app, event)
            value = app.LockinADCDropDown.Value;
            app.servo.lockinSelect.set(value);
            app.updateServo;
        end

        % Value changed function: DriveamplitudeVEditField
        function DriveamplitudeVEditFieldValueChanged(app, event)
            value = app.DriveamplitudeVEditField.Value;
            app.servo.lockin.driveAmp.set(value);
            app.updateServo;
        end

        % Callback function
        function OUT1DropDownValueChanged(app, event)
            value = app.OUT1DropDown.Value;
            app.servo.outputSelect(1).set(value);
            app.updateServo;
        end

        % Callback function
        function OUT2DropDownValueChanged(app, event)
            value = app.OUT2DropDown.Value;
            app.servo.outputSelect(2).set(value);
            app.updateServo;
        end

        % Value changed function: Log2ofCICshiftSpinner
        function Log2ofCICshiftSpinnerValueChanged(app, event)
            value = app.Log2ofCICshiftSpinner.Value;
            app.servo.lockin.shift.set(value);
            app.updateServo;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaserServoControlUIFigure = uifigure('Visible', 'off');
            app.LaserServoControlUIFigure.Name = 'Laser Servo Control';
            app.LaserServoControlUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @LaserServoControlUIFigureWindowButtonUp, true);
            app.UIAxes = uiaxes(app.LaserServoControlUIFigure);
            title(app.UIAxes, 'Measurements')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AcquisitionSettingsPanel = uipanel(app.LaserServoControlUIFigure);
            app.AcquisitionSettingsPanel.Title = 'Acquisition Settings';
            app.Log2CICRateSpinnerLabel = uilabel(app.AcquisitionSettingsPanel);
            app.Log2CICRateSpinnerLabel.Text = 'Log2(CIC Rate)';
            app.Log2CICRateSpinner = uispinner(app.AcquisitionSettingsPanel);
            app.Log2CICRateSpinner.Limits = [0 Inf];
            app.Log2CICRateSpinner.ValueChangedFcn = createCallbackFcn(app, @Log2CICRateSpinnerValueChanged, true);
            app.Log2CICRateSpinner.Value = 4;
            app.dtsEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.dtsEditFieldLabel.Text = 'dt [s]';
            app.dtsEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.dtsEditField.ValueDisplayFormat = '%.2e';
            app.dtsEditField.Editable = 'off';
            app.BWHzEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.BWHzEditFieldLabel.Text = 'BW [Hz]';
            app.BWHzEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.BWHzEditField.ValueDisplayFormat = '%.2e';
            app.BWHzEditField.Editable = 'off';
            app.ModulationFreqHzEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.ModulationFreqHzEditFieldLabel.Text = 'Modulation Freq [Hz]';
            app.ModulationFreqHzEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.ModulationFreqHzEditField.ValueDisplayFormat = '%3.3g';
            app.ModulationFreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @ModulationFreqHzEditFieldValueChanged, true);
            app.DriveamplitudeVEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.DriveamplitudeVEditFieldLabel.Text = 'Drive amplitude [V]';
            app.DriveamplitudeVEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.DriveamplitudeVEditField.ValueDisplayFormat = '%.1f';
            app.DriveamplitudeVEditField.ValueChangedFcn = createCallbackFcn(app, @DriveamplitudeVEditFieldValueChanged, true);
            app.DriveamplitudeVEditField.Value = 1;
            app.DemodPhase1degEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.DemodPhase1degEditFieldLabel.Text = 'Demod. Phase 1 [deg]';
            app.DemodPhase1degEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.DemodPhase1degEditField.ValueDisplayFormat = '%.1f';
            app.DemodPhase1degEditField.ValueChangedFcn = createCallbackFcn(app, @DemodPhase1degEditFieldValueChanged, true);
            app.DemodPhase2degEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.DemodPhase2degEditFieldLabel.Text = 'Demod. Phase 2 [deg]';
            app.DemodPhase2degEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.DemodPhase2degEditField.ValueDisplayFormat = '%.1f';
            app.Log2ofCICshiftSpinnerLabel = uilabel(app.AcquisitionSettingsPanel);
            app.Log2ofCICshiftSpinnerLabel.Text = 'Log2 of CIC shift';
            app.Log2ofCICshiftSpinner = uispinner(app.AcquisitionSettingsPanel);
            app.Log2ofCICshiftSpinner.Limits = [0 16];
            app.Log2ofCICshiftSpinner.ValueChangedFcn = createCallbackFcn(app, @Log2ofCICshiftSpinnerValueChanged, true);
            app.Log2ofCICshiftSpinner.Value = 12;
            app.ApplicationSettingsPanel = uipanel(app.LaserServoControlUIFigure);
            app.ApplicationSettingsPanel.Title = 'Application Settings';
            app.AutoUpdateSwitchLabel = uilabel(app.ApplicationSettingsPanel);
            app.AutoUpdateSwitchLabel.Text = 'Auto-Update';
            app.AutoUpdateSwitch = uiswitch(app.ApplicationSettingsPanel, 'slider');
            app.AutoUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateSwitchValueChanged, true);
            app.UploadButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FetchButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.RetrievescandataSwitchLabel = uilabel(app.ApplicationSettingsPanel);
            app.RetrievescandataSwitchLabel.Text = 'Retrieve scan data';
            app.RetrievescandataSwitch = uiswitch(app.ApplicationSettingsPanel, 'slider');
            app.RetrievescandataSwitch.ValueChangedFcn = createCallbackFcn(app, @RetrievescandataSwitchValueChanged, true);
            app.FetchDataButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.FetchDataButton.ButtonPushedFcn = createCallbackFcn(app, @FetchDataButtonPushed, true);
            app.FetchDataButton.Text = 'Fetch Data';
            app.PlotUpdateTimesEditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.PlotUpdateTimesEditFieldLabel.Text = 'Plot Update Time [s]';
            app.PlotUpdateTimesEditField = uieditfield(app.ApplicationSettingsPanel, 'numeric');
            app.PlotUpdateTimesEditField.Limits = [0 Inf];
            app.PlotUpdateTimesEditField.ValueDisplayFormat = '%4.2g';
            app.PlotUpdateTimesEditField.ValueChangedFcn = createCallbackFcn(app, @PlotUpdateTimesEditFieldValueChanged, true);
            app.PlotUpdateTimesEditField.Value = 0.1;
            app.PlotDropDown = uidropdown(app.ApplicationSettingsPanel);
            app.PlotDropDown.Items = {'Time', 'Volts', 'PSD'};
            app.PlotDropDown.Value = 'Time';
            app.XAxisDropDownLabel = uilabel(app.ApplicationSettingsPanel);
            app.XAxisDropDownLabel.Text = 'X Axis';
            app.XAxisDropDown = uidropdown(app.ApplicationSettingsPanel);
            app.XAxisDropDown.Items = {'Lin', 'Log'};
            app.XAxisDropDown.Value = 'Lin';
            app.YAxisDropDownLabel = uilabel(app.ApplicationSettingsPanel);
            app.YAxisDropDownLabel.Text = 'Y Axis';
            app.YAxisDropDown = uidropdown(app.ApplicationSettingsPanel);
            app.YAxisDropDown.Items = {'Lin', 'Log'};
            app.YAxisDropDown.Value = 'Lin';
            app.PlotDisplayDropDown = uidropdown(app.ApplicationSettingsPanel);
            app.PlotDisplayDropDown.Items = {'FIFO 1', 'FIFO 2', 'Both'};
            app.PlotDisplayDropDown.Value = 'Both';
            app.YLim1Label = uilabel(app.ApplicationSettingsPanel);
            app.YLim1Label.Text = 'YLim1';
            app.YLim1EditField = uieditfield(app.ApplicationSettingsPanel, 'text');
            app.YLim1EditField.Value = '-Inf,Inf';
            app.YLim2EditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.YLim2EditFieldLabel.Text = 'YLim2';
            app.YLim2EditField = uieditfield(app.ApplicationSettingsPanel, 'text');
            app.YLim2EditField.Value = '-Inf,Inf';
            app.LoadButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TabGroup2 = uitabgroup(app.LaserServoControlUIFigure);
            app.PID1Tab = uitab(app.TabGroup2);
            app.PID1Tab.Title = 'PID 1';
            app.RealKpEditField = uieditfield(app.PID1Tab, 'numeric');
            app.RealKpEditField.Editable = 'off';
            app.RealKiEditField = uieditfield(app.PID1Tab, 'numeric');
            app.RealKiEditField.Editable = 'off';
            app.RealKdEditField = uieditfield(app.PID1Tab, 'numeric');
            app.RealKdEditField.Editable = 'off';
            app.RealKiLabel = uilabel(app.PID1Tab);
            app.RealKiLabel.Text = '1/s';
            app.ReadKdLabel = uilabel(app.PID1Tab);
            app.ReadKdLabel.Text = 's';
            app.EnableSwitchLabel = uilabel(app.PID1Tab);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.PID1Tab, 'slider');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.PolaritySwitchLabel = uilabel(app.PID1Tab);
            app.PolaritySwitchLabel.Text = 'Polarity';
            app.PolaritySwitch = uiswitch(app.PID1Tab, 'slider');
            app.PolaritySwitch.Items = {'Negative', 'Positive'};
            app.PolaritySwitch.ValueChangedFcn = createCallbackFcn(app, @PolaritySwitchValueChanged, true);
            app.PolaritySwitch.Value = 'Negative';
            app.UpperLimitEditFieldLabel = uilabel(app.PID1Tab);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.PID1Tab, 'numeric');
            app.UpperLimitEditField.Limits = [-1 1];
            app.UpperLimitEditField.ValueDisplayFormat = '%4.4g';
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.UpperLimitEditField.Value = -1;
            app.SetPointEditField_2Label = uilabel(app.PID1Tab);
            app.SetPointEditField_2Label.Text = 'Set Point';
            app.SetPointEditField = uieditfield(app.PID1Tab, 'numeric');
            app.SetPointEditField.Limits = [-1 1];
            app.SetPointEditField.ValueDisplayFormat = '%4.4g';
            app.SetPointEditField.ValueChangedFcn = createCallbackFcn(app, @SetPointEditFieldValueChanged, true);
            app.LowerLimitEditFieldLabel = uilabel(app.PID1Tab);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.PID1Tab, 'numeric');
            app.LowerLimitEditField.Limits = [-1 1];
            app.LowerLimitEditField.ValueDisplayFormat = '%4.4g';
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.LowerLimitEditField.Value = 1;
            app.KpSpinnerLabel = uilabel(app.PID1Tab);
            app.KpSpinnerLabel.Text = 'Kp';
            app.KpSpinner = uispinner(app.PID1Tab);
            app.KpSpinner.Limits = [0 255];
            app.KpSpinner.RoundFractionalValues = 'on';
            app.KpSpinner.ValueDisplayFormat = '%d';
            app.KpSpinner.ValueChangedFcn = createCallbackFcn(app, @KpSpinnerValueChanged, true);
            app.KiSpinnerLabel = uilabel(app.PID1Tab);
            app.KiSpinnerLabel.Text = 'Ki';
            app.KiSpinner = uispinner(app.PID1Tab);
            app.KiSpinner.Limits = [0 255];
            app.KiSpinner.RoundFractionalValues = 'on';
            app.KiSpinner.ValueDisplayFormat = '%d';
            app.KiSpinner.ValueChangedFcn = createCallbackFcn(app, @KiSpinnerValueChanged, true);
            app.KdSpinnerLabel = uilabel(app.PID1Tab);
            app.KdSpinnerLabel.Text = 'Kd';
            app.KdSpinner = uispinner(app.PID1Tab);
            app.KdSpinner.Limits = [0 255];
            app.KdSpinner.RoundFractionalValues = 'on';
            app.KdSpinner.ValueDisplayFormat = '%d';
            app.KdSpinner.ValueChangedFcn = createCallbackFcn(app, @KdSpinnerValueChanged, true);
            app.DivisorSpinnerLabel = uilabel(app.PID1Tab);
            app.DivisorSpinnerLabel.Text = 'Divisor';
            app.DivisorSpinner = uispinner(app.PID1Tab);
            app.DivisorSpinner.Limits = [0 255];
            app.DivisorSpinner.RoundFractionalValues = 'on';
            app.DivisorSpinner.ValueDisplayFormat = '%d';
            app.DivisorSpinner.ValueChangedFcn = createCallbackFcn(app, @DivisorSpinnerValueChanged, true);
            app.PID2Tab = uitab(app.TabGroup2);
            app.PID2Tab.Title = 'PID 2';
            app.RealKpEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.RealKpEditField_2.Editable = 'off';
            app.RealKiEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.RealKiEditField_2.Editable = 'off';
            app.RealKdEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.RealKdEditField_2.Editable = 'off';
            app.RealKiLabel_2 = uilabel(app.PID2Tab);
            app.RealKiLabel_2.Text = '1/s';
            app.RealKdLabel_2 = uilabel(app.PID2Tab);
            app.RealKdLabel_2.Text = 's';
            app.EnableSwitch_2Label = uilabel(app.PID2Tab);
            app.EnableSwitch_2Label.Text = 'Enable';
            app.EnableSwitch_2 = uiswitch(app.PID2Tab, 'slider');
            app.EnableSwitch_2.ValueChangedFcn = createCallbackFcn(app, @EnableSwitch_2ValueChanged, true);
            app.PolaritySwitch_2Label = uilabel(app.PID2Tab);
            app.PolaritySwitch_2Label.Text = 'Polarity';
            app.PolaritySwitch_2 = uiswitch(app.PID2Tab, 'slider');
            app.PolaritySwitch_2.Items = {'Negative', 'Positive'};
            app.PolaritySwitch_2.ValueChangedFcn = createCallbackFcn(app, @PolaritySwitch_2ValueChanged, true);
            app.PolaritySwitch_2.Value = 'Negative';
            app.UpperLimitEditField_2Label = uilabel(app.PID2Tab);
            app.UpperLimitEditField_2Label.Text = 'Upper Limit';
            app.UpperLimitEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.UpperLimitEditField_2.Limits = [-1 1];
            app.UpperLimitEditField_2.ValueDisplayFormat = '%4.4g';
            app.UpperLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditField_2ValueChanged, true);
            app.UpperLimitEditField_2.Value = -1;
            app.SetPointEditField_3Label = uilabel(app.PID2Tab);
            app.SetPointEditField_3Label.Text = 'Set Point';
            app.SetPointEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.SetPointEditField_2.Limits = [-1 1];
            app.SetPointEditField_2.ValueDisplayFormat = '%4.4g';
            app.SetPointEditField_2.ValueChangedFcn = createCallbackFcn(app, @SetPointEditField_2ValueChanged, true);
            app.LowerLimitEditField_2Label = uilabel(app.PID2Tab);
            app.LowerLimitEditField_2Label.Text = 'Lower Limit';
            app.LowerLimitEditField_2 = uieditfield(app.PID2Tab, 'numeric');
            app.LowerLimitEditField_2.Limits = [-1 1];
            app.LowerLimitEditField_2.ValueDisplayFormat = '%4.4g';
            app.LowerLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditField_2ValueChanged, true);
            app.LowerLimitEditField_2.Value = 1;
            app.KpSpinner_2Label = uilabel(app.PID2Tab);
            app.KpSpinner_2Label.Text = 'Kp';
            app.KpSpinner_2 = uispinner(app.PID2Tab);
            app.KpSpinner_2.Limits = [0 255];
            app.KpSpinner_2.RoundFractionalValues = 'on';
            app.KpSpinner_2.ValueDisplayFormat = '%d';
            app.KpSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KpSpinner_2ValueChanged, true);
            app.KiSpinner_2Label = uilabel(app.PID2Tab);
            app.KiSpinner_2Label.Text = 'Ki';
            app.KiSpinner_2 = uispinner(app.PID2Tab);
            app.KiSpinner_2.Limits = [0 255];
            app.KiSpinner_2.RoundFractionalValues = 'on';
            app.KiSpinner_2.ValueDisplayFormat = '%d';
            app.KiSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KiSpinner_2ValueChanged, true);
            app.KdSpinner_2Label = uilabel(app.PID2Tab);
            app.KdSpinner_2Label.Text = 'Kd';
            app.KdSpinner_2 = uispinner(app.PID2Tab);
            app.KdSpinner_2.Limits = [0 255];
            app.KdSpinner_2.RoundFractionalValues = 'on';
            app.KdSpinner_2.ValueDisplayFormat = '%d';
            app.KdSpinner_2.ValueChangedFcn = createCallbackFcn(app, @KdSpinner_2ValueChanged, true);
            app.DivisorSpinner_2Label = uilabel(app.PID2Tab);
            app.DivisorSpinner_2Label.Text = 'Divisor';
            app.DivisorSpinner_2 = uispinner(app.PID2Tab);
            app.DivisorSpinner_2.Limits = [0 255];
            app.DivisorSpinner_2.RoundFractionalValues = 'on';
            app.DivisorSpinner_2.ValueDisplayFormat = '%d';
            app.DivisorSpinner_2.ValueChangedFcn = createCallbackFcn(app, @DivisorSpinner_2ValueChanged, true);
            app.PID3Tab = uitab(app.TabGroup2);
            app.PID3Tab.Title = 'PID 3';
            app.RealKpEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.RealKpEditField_3.Editable = 'off';
            app.RealKiEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.RealKiEditField_3.Editable = 'off';
            app.RealKdEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.RealKdEditField_3.Editable = 'off';
            app.RealKiLabel_3 = uilabel(app.PID3Tab);
            app.RealKiLabel_3.Text = '1/s';
            app.RealKdLabel_3 = uilabel(app.PID3Tab);
            app.RealKdLabel_3.Text = 's';
            app.EnableSwitch_3Label = uilabel(app.PID3Tab);
            app.EnableSwitch_3Label.Text = 'Enable';
            app.EnableSwitch_3 = uiswitch(app.PID3Tab, 'slider');
            app.PolaritySwitch_3Label = uilabel(app.PID3Tab);
            app.PolaritySwitch_3Label.Text = 'Polarity';
            app.PolaritySwitch_3 = uiswitch(app.PID3Tab, 'slider');
            app.PolaritySwitch_3.Items = {'Negative', 'Positive'};
            app.PolaritySwitch_3.Value = 'Negative';
            app.UpperLimitEditField_3Label = uilabel(app.PID3Tab);
            app.UpperLimitEditField_3Label.Text = 'Upper Limit';
            app.UpperLimitEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.UpperLimitEditField_3.Limits = [-1 1];
            app.UpperLimitEditField_3.ValueDisplayFormat = '%4.4g';
            app.UpperLimitEditField_3.Value = -1;
            app.SetPointEditField_3Label_2 = uilabel(app.PID3Tab);
            app.SetPointEditField_3Label_2.Text = 'Set Point';
            app.SetPointEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.SetPointEditField_3.Limits = [-1 1];
            app.SetPointEditField_3.ValueDisplayFormat = '%4.4g';
            app.LowerLimitEditField_3Label = uilabel(app.PID3Tab);
            app.LowerLimitEditField_3Label.Text = 'Lower Limit';
            app.LowerLimitEditField_3 = uieditfield(app.PID3Tab, 'numeric');
            app.LowerLimitEditField_3.Limits = [-1 1];
            app.LowerLimitEditField_3.ValueDisplayFormat = '%4.4g';
            app.LowerLimitEditField_3.Value = 1;
            app.KpSpinner_3Label = uilabel(app.PID3Tab);
            app.KpSpinner_3Label.Text = 'Kp';
            app.KpSpinner_3 = uispinner(app.PID3Tab);
            app.KpSpinner_3.Limits = [0 255];
            app.KpSpinner_3.RoundFractionalValues = 'on';
            app.KpSpinner_3.ValueDisplayFormat = '%d';
            app.KiSpinner_3Label = uilabel(app.PID3Tab);
            app.KiSpinner_3Label.Text = 'Ki';
            app.KiSpinner_3 = uispinner(app.PID3Tab);
            app.KiSpinner_3.Limits = [0 255];
            app.KiSpinner_3.RoundFractionalValues = 'on';
            app.KiSpinner_3.ValueDisplayFormat = '%d';
            app.KdSpinner_3Label = uilabel(app.PID3Tab);
            app.KdSpinner_3Label.Text = 'Kd';
            app.KdSpinner_3 = uispinner(app.PID3Tab);
            app.KdSpinner_3.Limits = [0 255];
            app.KdSpinner_3.RoundFractionalValues = 'on';
            app.KdSpinner_3.ValueDisplayFormat = '%d';
            app.DivisorSpinner_3Label = uilabel(app.PID3Tab);
            app.DivisorSpinner_3Label.Text = 'Divisor';
            app.DivisorSpinner_3 = uispinner(app.PID3Tab);
            app.DivisorSpinner_3.Limits = [0 255];
            app.DivisorSpinner_3.RoundFractionalValues = 'on';
            app.DivisorSpinner_3.ValueDisplayFormat = '%d';
            app.ManualControlPanel = uipanel(app.LaserServoControlUIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            % Show the figure after all components are created
            app.LaserServoControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IQ_Bias_Control_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LaserServoControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaserServoControlUIFigure)
        end
    end
end